classdef FideliteClient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Top3Button         matlab.ui.control.Button
        RetourButton       matlab.ui.control.Button
        Image4             matlab.ui.control.Image
        TopVisiteursLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RetourButton
        function RetourButtonPushed(app, event)
            %Si on appui sur le bouton retour2 ouvrir fenetre app2 et fermer la fenetre presente
                         
            frequentationparj = app2
            app.UIFigure.Visible = 'off'
            close(gcf) %Fermer le graphe
        end

        % Button pushed function: Top3Button
        function Top3ButtonPushed(app, event)
            % Charger les données du fichier Excel de la feuille data
            file = 'echantillon_taille_francais.xlsx';
            sheet = 'data';
            [num, txt, raw] = xlsread(file, sheet);

            client_nom = raw(2:end, 3); %extraire toutes les lignes de la 3eme colonne du fichier à partir de la 2eme ligne
            client_prenom = raw(2:end, 2);
            nb_entree = cell2mat(raw(2:end, 8)); %cell2mat permet de convertir un tableau de cellule en données numerique
            
            %Trier tous les client en fonction de leurs nombre d'entrees de
            %facon decroissante
            [entree_triee, indice_entree_triee] = sort(nb_entree, 'descend');
            %il faut tirer les noms et prenoms des 3 premiers
            premiers_noms = client_nom(indice_entree_triee);
            premiers_prenom = client_prenom(indice_entree_triee);
            premiers_entree = nb_entree(indice_entree_triee);

            %Extraire les clients de la liste
            clients_extraits_noms = premiers_noms(1:3);
            clients_extraits_prenom = premiers_prenom(1:3);
            clients_extraits_entrees = premiers_entree(1:3);
            
            %Creation podium (graphe a barre)
            bar(app.UIAxes, clients_extraits_entrees);
            xticklabels({[clients_extraits_prenom{1}, '', clients_extraits_noms{1}], [clients_extraits_noms{2}, '', clients_extraits_prenom{2}], [clients_extraits_noms{3}, '', clients_extraits_prenom{3}]});
            title(app.UIAxes, 'Top 3 clients');
            xlabel(app.UIAxes, 'Clients');
            ylabel(app.UIAxes, 'Nb_entree');

            text(1, clients_extraits_entrees(1), num2str(clients_extraits_entrees(1)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            text(2, clients_extraits_entrees(2), num2str(clients_extraits_entrees(2)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            text(3, clients_extraits_entrees(3), num2str(clients_extraits_entrees(3)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.TopVisiteursLabel = uilabel(app.UIFigure);
            app.TopVisiteursLabel.Text = 'Top Visiteurs';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo salle de sport.jpeg');
            app.RetourButton = uibutton(app.UIFigure, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @RetourButtonPushed, true);
            app.RetourButton.Text = 'Retour';
            app.Top3Button = uibutton(app.UIFigure, 'push');
            app.Top3Button.ButtonPushedFcn = createCallbackFcn(app, @Top3ButtonPushed, true);
            app.Top3Button.Text = 'Top 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FideliteClient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end