classdef GraphFJ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image4        matlab.ui.control.Image
        RetourButton  matlab.ui.control.Button
        UIAxes2       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        UIAxes matlab.ui.control.UIAxes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function create(app)
            % Charger les données du fichier Excel de la feuille FreqJ
            file = 'echantillon_taille_francais.xlsx';
            sheet = 'FreqJ';
            [num, txt, raw] = xlsread(file, sheet);
            
            %Extraire données jours de la semaine et le nombre tot visites
            jours = raw(1,2:end); %extraire toutes les colonnes de la 1ere ligne du fichier à partir de la 2eme colonne
            nb_tot_vis = cell2mat(raw(15, 2:end)); %on fait pareil que avant + cell2mat permet de convertir un tableau de cellule en données num
                        
            % Créer le graphique à barres
            axes(app.UIAxes2)
            bar(app.UIAxes2, nb_tot_vis);

            % Ajouter un titre et des étiquettes d'axe (que pour y car sur
            % x jours de la semaine)
            title(app.UIAxes2, 'Fréquentation par jour', 'FontSize', 14);
            
            ylabel(app.UIAxes2, 'Nombre de visiteurs')
            
            % Dimensionnement des axes
            xticks(app.UIAxes2, 1:length(jours)) % sur ces deux lignes on definit sur x les noms de chaques barres, soit les jours de la semaine
            xticklabels(app.UIAxes2, jours)
            xlabel(app.UIAxes2, '') %Pas de nom pour l'axe X
            
            
        end

        % Button pushed function: RetourButton
        function RetourButtonPushed(app, event)
            %Si on appui sur le bouton retour, ouvrir fenetre app2 et
            %fermer la fenetre presente
            frequentationparj = app2
            app.UIFigure.Visible = 'off'
            close(gcf) %Fermer le graphe
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RetourButton = uibutton(app.UIFigure, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @RetourButtonPushed, true);
            app.RetourButton.Text = 'Retour';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo salle de sport.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphFJ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @create)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end