classdef Graphiques < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SnackButton         matlab.ui.control.Button
        RetourButton_2      matlab.ui.control.Button
        RetourButton        matlab.ui.control.Button
        Image4              matlab.ui.control.Image
        ProportionHFButton  matlab.ui.control.Button
        GRAPHIQUESLabel     matlab.ui.control.Label
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProportionHFButton
        function ProportionHFButtonPushed(app, event)
            % Charger les données du fichier Excel de la feuille data
            file = 'echantillon_taille_francais.xlsx';
            sheet = 'data';
            [num, txt, raw] = xlsread(file, sheet);

            Sexes = raw(2:end, 5); %extraire toutes les lignes de la 5eme colonne du fichier à partir de la 2eme ligne

            %Compter nb hommes femmes
            nb_hommes = sum(strcmpi(Sexes, 'Male'));
            nb_femmes = sum(strcmpi(Sexes, 'Female'));

            %Ajouter pourecentages dans le camembert
            rep_hommes = nb_hommes/(nb_hommes + nb_femmes)*100;
            rep_femmes = nb_femmes/(nb_hommes + nb_femmes)*100;
            
            %Camembert
            axes(app.UIAxes);
            pie(app.UIAxes, [nb_femmes nb_hommes], {sprintf('Hommes (%1.f%%)', rep_hommes), sprintf('Fommes (%1.f%%)', rep_femmes)}); %On affiche aussi les pourcentages correspondants
            
            %ajouter legende titre 
            legend(app.UIAxes, 'Orientation', 'horizontal', 'Location', 'southoutside');
            title(app.UIAxes, 'Repartition des sexes');
            app.ProportionHFButton.Visible = 'off'
            app.GRAPHIQUESLabel.Visible = 'off'
            app.RetourButton.Visible = 'on'
            app.RetourButton_2.Visible = 'off'
            app.SnackButton.Visible = "off"
        end

        % Button pushed function: RetourButton
        function RetourButtonPushed(app, event)
            %Si on appui sur le bouton retour, faire apparaitre les boutons Snack et Proportion et faire disparaitre tous les graphiques
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, ''); %faire disparaitre titre camembert
            legend(app.UIAxes, 'off'); %Pareil que pour le titre
            app.RetourButton.Visible = 'off'
            app.ProportionHFButton.Visible = 'on'
            app.GRAPHIQUESLabel.Visible = 'on'
            app.RetourButton_2.Visible = 'on'
            app.SnackButton.Visible = "on"
            
        end

        % Button pushed function: RetourButton_2
        function RetourButton_2Pushed(app, event)
            %Si on appui sur le bouton retour2 (c'est le premier, on a juste oublier de le mettre au début), ouvrir fenetre app2 et fermer la fenetre presente
                         
            frequentationparj = app2
            app.UIFigure.Visible = 'off'
            close(gcf) %Fermer le graphe
        end

        % Button pushed function: SnackButton
        function SnackButtonPushed(app, event)
            % Charger les données du fichier Excel de la feuille Snack
            file = 'echantillon_taille_francais.xlsx';
            sheet = 'Snack';
            [num, txt, raw] = xlsread(file, sheet);

            %Graphe Boissons
            Boissons = raw(2:6, 1); %extraire toutes les lignes de la 1eme colonne du fichier à partir de la 2eme ligne jusqua la 6eme
            quantites = num(1:5, 2); %Extraire lignes 1 à 5 de la 2eme colonne

            %Histogramme horizontal
            axes(app.UIAxes2);
            barh(app.UIAxes2, quantites);
            yticklabels(app.UIAxes2, Boissons); %Sur y on aura les boissons
            xlabel(app.UIAxes2, 'Quantité');
            ylabel(app.UIAxes2, 'Boisson');
            title(app.UIAxes2, 'Quantités boissons restantes');

            %Graphe barres energisantes
            manger = raw(2:6, 3); %extraire toutes les lignes de la 3eme colonne du fichier à partir de la 2eme ligne jusqua la 6eme
            quantites2 = num(1:5, 3); %Extraire lignes 1 à 5 de la 4eme colonne

            %Histogramme horizontal
            axes(app.UIAxes3);
            barh(app.UIAxes3, quantites2);
            yticklabels(app.UIAxes3, manger); %Sur y on aura les barres energisantes
            xlabel(app.UIAxes3, 'Quantité2');
            ylabel(app.UIAxes3, 'Manger');
            title(app.UIAxes3, 'Quantités barres restantes');


            app.RetourButton.Visible = 'on'
            app.RetourButton_2.Visible = 'off'
            app.SnackButton.Visible = "off"
            app.ProportionHFButton.Visible = 'off'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.GRAPHIQUESLabel = uilabel(app.UIFigure);
            app.GRAPHIQUESLabel.Text = 'GRAPHIQUES';
            app.ProportionHFButton = uibutton(app.UIFigure, 'push');
            app.ProportionHFButton.ButtonPushedFcn = createCallbackFcn(app, @ProportionHFButtonPushed, true);
            app.ProportionHFButton.Text = 'Proportion H/F';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo salle de sport.jpeg');
            app.RetourButton = uibutton(app.UIFigure, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @RetourButtonPushed, true);
            app.RetourButton.Visible = 'off';
            app.RetourButton.Text = 'Retour';
            app.RetourButton_2 = uibutton(app.UIFigure, 'push');
            app.RetourButton_2.ButtonPushedFcn = createCallbackFcn(app, @RetourButton_2Pushed, true);
            app.RetourButton_2.Text = 'Retour';
            app.SnackButton = uibutton(app.UIFigure, 'push');
            app.SnackButton.ButtonPushedFcn = createCallbackFcn(app, @SnackButtonPushed, true);
            app.SnackButton.Text = 'Snack';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graphiques

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end