classdef app1entree < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image4                  matlab.ui.control.Image
        Panel                   matlab.ui.container.Panel
        ConnexionButton         matlab.ui.control.Button
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        ALPHAStatisticsLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        Password % propriété pour stocker le mdp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PasswordEditFieldValueChanged(app, event)
            % Stocker le mdp non masqué dans une propriété de l'application
            app.Password = event.Source.Value;          
            

            % Vérifier si l'utilisateur est en train de saisir le mdp
            if strcmp(app.UsernameEditField.Value, '') || strcmp(app.PasswordEditField.Value, '')
                % Ne pas masquer le mdp si l'utilisateur est en train de saisir
                event.Source.Value = app.Password;
            else %Sinon on le masque
                maskedPassword = repmat('*', 1, length(app.Password));
                event.Source.Value = maskedPassword;
            end


        end

        % Button pushed function: ConnexionButton
        function ConnexionButtonPushed(app, event)
            %On récupére le login et le mdp
            username = app.UsernameEditField.Value;
            password = app.PasswordEditField.Value;
           
            
            %Vérifier si login et mdp bons
            correct_username = 'lasalledesport@sport.fr';
            correct_password = '123';
            %Si oui on ferme cette page et on ouvre la page app2
            if strcmp(username, correct_username) && strcmp(password, correct_password)
                app.UIFigure.Visible = 'off'
                openWindow(app2, 'Fenetre deux apparemment');              
            else 
                %Sinon message erreur
                uialert(app.UIFigure, 'Login ou Password incorrect', 'Reéssayez');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ALPHAStatisticsLabel = uilabel(app.UIFigure);
            app.ALPHAStatisticsLabel.Text = 'ALPHA Statistics';
            app.Panel = uipanel(app.UIFigure);
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.Panel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Panel, 'text');
            app.ConnexionButton = uibutton(app.Panel, 'push');
            app.ConnexionButton.ButtonPushedFcn = createCallbackFcn(app, @ConnexionButtonPushed, true);
            app.ConnexionButton.Text = 'Connexion';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo salle de sport.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1entree

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end