classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image4                      matlab.ui.control.Image
        BienvenuedansvotresalledesportLabel  matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        FidelitButton               matlab.ui.control.Button
        FrequentationparjourButton  matlab.ui.control.Button
        GraphiquesButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraphiquesButton
        function GraphiquesButtonPushed(app, event)
            %Lorsqu on appui sur Boutton Graphiques ouvrir code nommé
            %Graphiques, fermer la fenetre presente
            graphiques = Graphiques
            app.UIFigure.Visible = 'off'
            graphiques.Visible = 'on';
        end

        % Button pushed function: FrequentationparjourButton
        function FrequentationparjourButtonPushed(app, event)
            %Lorsqu on appui sur Boutton frequentation par jour ouvrir code nommé
            %GraphFJ, fermer la fenetre presente
            frequentationparj = GraphFJ
            app.UIFigure.Visible = 'off'
            frequentationparj.Visible = 'on';
        end

        % Button pushed function: FidelitButton
        function FidelitButtonPushed(app, event)
            %Lorsqu on appui sur Boutton Fidelite ouvrir code nommé
            %FideliteClient et fermer la fenetre presente            
            fideliteclient = FideliteClient
            app.UIFigure.Visible = 'off'
            fideliteclient.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphiquesButton = uibutton(app.UIFigure, 'push');
            app.GraphiquesButton.ButtonPushedFcn = createCallbackFcn(app, @GraphiquesButtonPushed, true);
            app.GraphiquesButton.Text = 'Graphiques';
            app.FrequentationparjourButton = uibutton(app.UIFigure, 'push');
            app.FrequentationparjourButton.ButtonPushedFcn = createCallbackFcn(app, @FrequentationparjourButtonPushed, true);
            app.FrequentationparjourButton.Text = 'Frequentation par jour';
            app.FidelitButton = uibutton(app.UIFigure, 'push');
            app.FidelitButton.ButtonPushedFcn = createCallbackFcn(app, @FidelitButtonPushed, true);
            app.FidelitButton.Text = 'Fidelité';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image musculation.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'image graphique.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Image fidelite.jpg');
            app.BienvenuedansvotresalledesportLabel = uilabel(app.UIFigure);
            app.BienvenuedansvotresalledesportLabel.Text = 'Bienvenue dans votre salle de sport! ';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo salle de sport.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end